classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LeftGridLayout             matlab.ui.container.GridLayout
        SettingsLabel              matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        VisionTab                  matlab.ui.container.Tab
        VisionGridLayout           matlab.ui.container.GridLayout
        AquireImageButton          matlab.ui.control.Button
        ZDepthEditFieldLabel       matlab.ui.control.Label
        ZDepthEditField            matlab.ui.control.NumericEditField
        CalculatePointsButton      matlab.ui.control.Button
        ManualStartGuessButton     matlab.ui.control.Button
        AutoStartGuessButton       matlab.ui.control.Button
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        PathingTab                 matlab.ui.container.Tab
        PathingGridLayout          matlab.ui.container.GridLayout
        DownsampleEditFieldLabel   matlab.ui.control.Label
        DownsampleEditField        matlab.ui.control.NumericEditField
        MaxDistanceEditFieldLabel  matlab.ui.control.Label
        MaxDistanceEditField       matlab.ui.control.NumericEditField
        AveragingEditFieldLabel    matlab.ui.control.Label
        AveragingEditField         matlab.ui.control.NumericEditField
        GeneratePathButton         matlab.ui.control.Button
        SmoothingEditFieldLabel    matlab.ui.control.Label
        SmoothingEditField         matlab.ui.control.NumericEditField
        SamplesEditFieldLabel      matlab.ui.control.Label
        SamplesEditField           matlab.ui.control.NumericEditField
        ResampleButton             matlab.ui.control.Button
        PointsEditFieldLabel       matlab.ui.control.Label
        PointsEditField            matlab.ui.control.NumericEditField
        DownsampleButton           matlab.ui.control.Button
        ShowSplineButton           matlab.ui.control.Button
        RMRCTab                    matlab.ui.container.Tab
        RMRCGridLayout             matlab.ui.container.GridLayout
        CollisionCheckSwitchLabel  matlab.ui.control.Label
        CollisionCheckSwitch       matlab.ui.control.Switch
        LoadTrajectoryButton       matlab.ui.control.Button
        ReinitialiseButton         matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        RightGridLayout            matlab.ui.container.GridLayout
        RightTabGroup              matlab.ui.container.TabGroup
        AxesTab                    matlab.ui.container.Tab
        AxisGridLayout             matlab.ui.container.GridLayout
        UIAxes                     matlab.ui.control.UIAxes
        ControlTab                 matlab.ui.container.Tab
        ControlGridLayout          matlab.ui.container.GridLayout
        ControlLabel               matlab.ui.control.Label
        EStopButton                matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        DobotStatsPanel            matlab.ui.container.Panel
        DobotJointsGridLayout      matlab.ui.container.GridLayout
        Joint1EditFieldLabel       matlab.ui.control.Label
        DobotJoint1EditField       matlab.ui.control.NumericEditField
        Joint2EditFieldLabel       matlab.ui.control.Label
        DobotJoint2EditField       matlab.ui.control.NumericEditField
        Joint3EditFieldLabel       matlab.ui.control.Label
        DobotJoint3EditField       matlab.ui.control.NumericEditField
        Joint4EditFieldLabel       matlab.ui.control.Label
        DobotJoint4EditField       matlab.ui.control.NumericEditField
        Joint5EditFieldLabel       matlab.ui.control.Label
        DobotJoint5EditField       matlab.ui.control.NumericEditField
        ARTagsPositionsPanel       matlab.ui.container.Panel
        ARGridLayout               matlab.ui.container.GridLayout
        PathEditFieldLabel         matlab.ui.control.Label
        PathEditField              matlab.ui.control.EditField
        CameraEditFieldLabel       matlab.ui.control.Label
        CameraEditField            matlab.ui.control.EditField
        DobotEditFieldLabel        matlab.ui.control.Label
        DobotEditField             matlab.ui.control.EditField
        LogTextArea                matlab.ui.control.TextArea
        StatusGridLayout           matlab.ui.container.GridLayout
        StatusIndicatorLampLabel   matlab.ui.control.Label
        StatusIndicatorLamp        matlab.ui.control.Lamp
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        intialised % Only run robot once path has been set
        rosMode logical
        controller Controller % System Controller
    end
    
    methods (Access = private)
        function SetStatus(app, code)
            switch(lower(code))
                case 'environmentnotsetup'
                    colour = 'yellow';
                    text = 'Environment not setup';
                case 'environmentsettingup'
                    colour = 'yellow';
                    text = 'Environment setting up';
                case 'environmentsetup'
                    colour = 'green';
                    text = 'Environment setup';
                case 'running'
                    colour = 'green';
                    text = 'Simulation running';           
                case 'estop'
                    colour = 'red';
                    text = 'E Stop triggered, reset environment to continue';
                case 'nopath'
                    colour = 'red';
                    text = 'No Path Found';
                otherwise
                    colour = 'magenta';
                    text = 'Unknown status code';
            end
            app.StatusIndicatorLamp.Color = colour;
            app.StatusIndicatorLampLabel.Text = text;
        end
        
        function ResetAxis(app)
            cla(app.UIAxes,'reset');
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.ZLimMode = 'auto';
            app.UIAxes.NextPlot = 'replace';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ros, debug)
            if ~exist('ros','var')
                ros = false;
            end
            if ~exist('debug','var')
                debug = false;
            end
            app.rosMode = ros;
            app.controller = Controller(ros, debug);
            cla(app.UIAxes,'reset');
            app.SetStatus('environmentnotsetup')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {241, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AquireImageButton
        function AquireImageButtonPushed(app, event)
            app.controller.AcquireImageMask();
            app.CalculatePointsButton.Enable = true;
            app.ResetAxis();
            app.controller.ShowPathImage(app.UIAxes);
        end

        % Button pushed function: CalculatePointsButton
        function CalculatePointsButtonPushed(app, event)
            zDepth = app.ZDepthEditField.Value;
            if zDepth > 0
                points = app.controller.GeneratePathPoints(zDepth);
            else
                points = app.controller.GeneratePathPoints();
            end
            app.PointsEditField.Value = length(points);
            app.AutoStartGuessButton.Enable = true;
            app.ManualStartGuessButton.Enable = true;
            app.ResetAxis();
            app.controller.ShowPathPointCloud(app.UIAxes);
        end

        % Button pushed function: DownsampleButton
        function DownsampleButtonPushed(app, event)
            downsample = app.DownsampleEditField.Value;
            app.controller.DownsamplePathPoints(downsample)
            app.ResetAxis();
            app.controller.ShowPathPointCloud(app.UIAxes);
        end

        % Button pushed function: GeneratePathButton
        function GeneratePathButtonPushed(app, event)
            maxDistance = app.MaxDistanceEditField.Value;
            averaging = app.AveragingEditField.Value;
            smoothing = app.SmoothingEditField.Value;
            success = app.controller.GeneratePath(maxDistance, averaging, smoothing);
            if success == true
                app.ShowSplineButton.Enable = true;
                app.ResampleButton.Enable = true;
                app.controller.ShowPathSpline(app.UIAxes);
            else
                app.SetStatus('nopath');
            end
        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            resample = app.SamplesEditField.Value;
            app.controller.GetTrajectory(resample);
            app.LoadTrajectoryButton.Enable = true;
        end

        % Button pushed function: LoadTrajectoryButton
        function LoadTrajectoryButtonPushed(app, event)
            app.LoadTrajectoryButton();
            app.StartButton.Enable = true;
            app.ReinitialiseButton.Enable = true;
        end

        % Button pushed function: ShowSplineButton
        function ShowSplineButtonPushed(app, event)
            app.ResetAxis();
            app.controller.ShowPathSpline(app.UIAxes);
        end

        % Button pushed function: AutoStartGuessButton
        function AutoStartGuessButtonPushed(app, event)
            app.controller.UpdatePathStartGuess();
            app.DownsampleButton.Enable = true;
            app.GeneratePathButton.Enable = true;
        end

        % Button pushed function: ManualStartGuessButton
        function ManualStartGuessButtonPushed(app, event)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
            app.controller.UpdatePathStartGuess([x,y,z]);
            app.DownsampleButton.Enable = true;
            app.GeneratePathButton.Enable = true;
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.controller.EStop()
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.controller.Run();
        end

        % Button pushed function: ReinitialiseButton
        function ReinitialiseButtonPushed(app, event)
            app.controller.Initialise();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGridLayout = uigridlayout(app.LeftPanel);
            app.SettingsLabel = uilabel(app.LeftGridLayout);
            app.SettingsLabel.Text = 'Settings';
            app.TabGroup = uitabgroup(app.LeftGridLayout);
            app.VisionTab = uitab(app.TabGroup);
            app.VisionTab.Title = 'Vision';
            app.VisionGridLayout = uigridlayout(app.VisionTab);
            app.AquireImageButton = uibutton(app.VisionGridLayout, 'push');
            app.AquireImageButton.ButtonPushedFcn = createCallbackFcn(app, @AquireImageButtonPushed, true);
            app.AquireImageButton.Text = 'Aquire Image';
            app.ZDepthEditFieldLabel = uilabel(app.VisionGridLayout);
            app.ZDepthEditFieldLabel.Text = 'Z Depth';
            app.ZDepthEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.CalculatePointsButton = uibutton(app.VisionGridLayout, 'push');
            app.CalculatePointsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePointsButtonPushed, true);
            app.CalculatePointsButton.Enable = 'off';
            app.CalculatePointsButton.Text = 'Calculate Points';
            app.ManualStartGuessButton = uibutton(app.VisionGridLayout, 'push');
            app.ManualStartGuessButton.ButtonPushedFcn = createCallbackFcn(app, @ManualStartGuessButtonPushed, true);
            app.ManualStartGuessButton.Enable = 'off';
            app.ManualStartGuessButton.Text = 'Manual Start Guess';
            app.AutoStartGuessButton = uibutton(app.VisionGridLayout, 'push');
            app.AutoStartGuessButton.ButtonPushedFcn = createCallbackFcn(app, @AutoStartGuessButtonPushed, true);
            app.AutoStartGuessButton.Enable = 'off';
            app.AutoStartGuessButton.Text = 'Auto Start Guess';
            app.XEditFieldLabel = uilabel(app.VisionGridLayout);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.YEditFieldLabel = uilabel(app.VisionGridLayout);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.ZEditFieldLabel = uilabel(app.VisionGridLayout);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.PathingTab = uitab(app.TabGroup);
            app.PathingTab.Title = 'Pathing';
            app.PathingGridLayout = uigridlayout(app.PathingTab);
            app.DownsampleEditFieldLabel = uilabel(app.PathingGridLayout);
            app.DownsampleEditFieldLabel.Text = 'Downsample';
            app.DownsampleEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.DownsampleEditField.Value = 200;
            app.MaxDistanceEditFieldLabel = uilabel(app.PathingGridLayout);
            app.MaxDistanceEditFieldLabel.Text = 'Max Distance';
            app.MaxDistanceEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.MaxDistanceEditField.Value = 0.1;
            app.AveragingEditFieldLabel = uilabel(app.PathingGridLayout);
            app.AveragingEditFieldLabel.Text = 'Averaging';
            app.AveragingEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.AveragingEditField.Value = 4;
            app.GeneratePathButton = uibutton(app.PathingGridLayout, 'push');
            app.GeneratePathButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePathButtonPushed, true);
            app.GeneratePathButton.Enable = 'off';
            app.GeneratePathButton.Text = 'Generate Path';
            app.SmoothingEditFieldLabel = uilabel(app.PathingGridLayout);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.SmoothingEditField.Value = 0.5;
            app.SamplesEditFieldLabel = uilabel(app.PathingGridLayout);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.SamplesEditField.Value = 400;
            app.ResampleButton = uibutton(app.PathingGridLayout, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Enable = 'off';
            app.ResampleButton.Text = 'Resample';
            app.PointsEditFieldLabel = uilabel(app.PathingGridLayout);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.PointsEditField.ValueDisplayFormat = '%111.4g';
            app.PointsEditField.Editable = 'off';
            app.DownsampleButton = uibutton(app.PathingGridLayout, 'push');
            app.DownsampleButton.ButtonPushedFcn = createCallbackFcn(app, @DownsampleButtonPushed, true);
            app.DownsampleButton.Enable = 'off';
            app.DownsampleButton.Text = 'Downsample';
            app.ShowSplineButton = uibutton(app.PathingGridLayout, 'push');
            app.ShowSplineButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSplineButtonPushed, true);
            app.ShowSplineButton.Enable = 'off';
            app.ShowSplineButton.Text = 'Show Spline';
            app.RMRCTab = uitab(app.TabGroup);
            app.RMRCTab.Title = 'RMRC';
            app.RMRCGridLayout = uigridlayout(app.RMRCTab);
            app.CollisionCheckSwitchLabel = uilabel(app.RMRCGridLayout);
            app.CollisionCheckSwitchLabel.Text = 'Collision Check';
            app.CollisionCheckSwitch = uiswitch(app.RMRCGridLayout, 'slider');
            app.LoadTrajectoryButton = uibutton(app.RMRCGridLayout, 'push');
            app.LoadTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoryButtonPushed, true);
            app.LoadTrajectoryButton.Enable = 'off';
            app.LoadTrajectoryButton.Text = 'Load Trajectory';
            app.ReinitialiseButton = uibutton(app.RMRCGridLayout, 'push');
            app.ReinitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitialiseButtonPushed, true);
            app.ReinitialiseButton.Enable = 'off';
            app.ReinitialiseButton.Text = 'Reinitialise';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGridLayout = uigridlayout(app.RightPanel);
            app.RightTabGroup = uitabgroup(app.RightGridLayout);
            app.AxesTab = uitab(app.RightTabGroup);
            app.AxesTab.Title = 'Axes';
            app.AxisGridLayout = uigridlayout(app.AxesTab);
            app.UIAxes = uiaxes(app.AxisGridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.ControlTab = uitab(app.RightTabGroup);
            app.ControlTab.Title = 'Control';
            app.ControlGridLayout = uigridlayout(app.ControlTab);
            app.ControlLabel = uilabel(app.ControlGridLayout);
            app.ControlLabel.Text = 'Control';
            app.EStopButton = uibutton(app.ControlGridLayout, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E Stop';
            app.StartButton = uibutton(app.ControlGridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.DobotStatsPanel = uipanel(app.ControlGridLayout);
            app.DobotStatsPanel.Title = 'Dobot Stats';
            app.DobotJointsGridLayout = uigridlayout(app.DobotStatsPanel);
            app.Joint1EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.DobotJoint1EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint1EditField.Editable = 'off';
            app.Joint2EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.DobotJoint2EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint2EditField.Editable = 'off';
            app.Joint3EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.DobotJoint3EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint3EditField.Editable = 'off';
            app.Joint4EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.DobotJoint4EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint4EditField.Editable = 'off';
            app.Joint5EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.DobotJoint5EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint5EditField.Editable = 'off';
            app.ARTagsPositionsPanel = uipanel(app.ControlGridLayout);
            app.ARTagsPositionsPanel.Title = 'AR Tags Positions';
            app.ARGridLayout = uigridlayout(app.ARTagsPositionsPanel);
            app.PathEditFieldLabel = uilabel(app.ARGridLayout);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.ARGridLayout, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = 'N/A';
            app.CameraEditFieldLabel = uilabel(app.ARGridLayout);
            app.CameraEditFieldLabel.Text = 'Camera';
            app.CameraEditField = uieditfield(app.ARGridLayout, 'text');
            app.CameraEditField.Editable = 'off';
            app.CameraEditField.Value = 'N/A';
            app.DobotEditFieldLabel = uilabel(app.ARGridLayout);
            app.DobotEditFieldLabel.Text = 'Dobot';
            app.DobotEditField = uieditfield(app.ARGridLayout, 'text');
            app.DobotEditField.Editable = 'off';
            app.DobotEditField.Value = 'N/A';
            app.LogTextArea = uitextarea(app.ControlGridLayout);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Tooltip = {'Execution Log'};
            app.StatusGridLayout = uigridlayout(app.RightGridLayout);
            app.StatusIndicatorLampLabel = uilabel(app.StatusGridLayout);
            app.StatusIndicatorLampLabel.Text = 'Status Indicator';
            app.StatusIndicatorLamp = uilamp(app.StatusGridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end